classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        REDNEURONALDEFUNCIONESDEBASERADIALRBFLabel  matlab.ui.control.Label
        EntrenarButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        RutadelarchivoLabel           matlab.ui.control.Label
        ERRORDEAPROXIMACIONEditField  matlab.ui.control.NumericEditField
        ERRORDEAPROXIMACIONEditFieldLabel  matlab.ui.control.Label
        CargardatosButton             matlab.ui.control.Button
        NUMERODECENTROSRADIALESEditField  matlab.ui.control.NumericEditField
        NUMERODECENTROSRADIALESEditFieldLabel  matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data="null";
        entrada;
        salida;
        centro_radial;
        cantidad_entradas;
        cantidad_patrones;
        cantidad_CR;
        cantidad_salidas;
       cantidad_entrenamientos;
        errorMaximopermi;
    end


    methods (Access = public)
        
        function DescripcionMatrizEntrenamiento(app)
            tamEntrada=size(app.data);
            app.cantidad_entradas=tamEntrada(2)-1;
           
            app.cantidad_salidas=1;
           
            app.cantidad_patrones=tamEntrada(1);
           
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargardatosButton
        function CargardatosButtonPushed(app, event)
          [nombre, directorio]=uigetfile({'*.xlsx','Archivo Excel'},'Escoge un archivo');
          app.RutadelarchivoLabel.Text=directorio+""+nombre;
          app.data=xlsread(app.RutadelarchivoLabel.Text);
          app.UITable.Data=app.data;     
          DescripcionMatrizEntrenamiento(app);
          disp(app.cantidad_entradas);
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
                        %CANTIDAD DE CENTROS RADIALES Y ERROR MAXIMO
                        %PERMITIDO
                        
              app.errorMaximopermi=app.ERRORDEAPROXIMACIONEditField.Value;
              app.cantidad_CR=app.NUMERODECENTROSRADIALESEditField.Value;
              
              
              %Definimos entradas y salidas 
                app.entrada=app.data(:,1:app.cantidad_entradas);
                app.salida=app.data(:,(app.cantidad_entradas+1));
                        
            % calculamos distancia euclidiana y funcion radial
            Dist_Euc=[];
            Funcion_BR=[];
            acum=0;
            err=1;
            e=1;
              disp(app.salida);
             matrizSimulacion=zeros(app.cantidad_patrones,3);
            erroresIteraciones=zeros(50,2);
            
            while(and(app.errorMaximopermi<err,e<=100))
          
            app.centro_radial=min(min(app.entrada))+(max(max(app.entrada))-min(min(app.entrada))).*rand(app.cantidad_CR,app.cantidad_entradas);
            
            for i=1:app.cantidad_patrones
               
                for k=1:app.cantidad_CR
                    acum=0;
                for j=1:app.cantidad_entradas
                acum=acum+((app.entrada(i,j))-(app.centro_radial(k,j)))^2;
                end
                Dist_Euc(i,k)=sqrt(acum);
                %Funcion de activacion para las distacias
                Funcion_BR(i,k)=(sqrt(acum)^2)*log(sqrt(acum));
                end
            end
            
            %Funcion de base radial
            
            %Matriz de interpolacion
            unos=ones(app.cantidad_patrones,1);
            matrizInterpolacion=[unos Funcion_BR];
            err=0;
            
            
            Incognitas=matrizInterpolacion\app.salida;
              resultado=matrizInterpolacion*Incognitas;
              
            for i=1:app.cantidad_patrones
                
                  err=err+abs(app.salida(i)-resultado(i));
                  %capturamos los resultados de las salidas deseadas con
                  %las salidas obtenidas para graficar los resultados 
                    matrizSimulacion(i,1)=i;
                    matrizSimulacion(i,2)=app.salida(i,:);
                    matrizSimulacion(i,3)=resultado(i);
            end
            %capturamos el error del entrenamiento
                  erroresIteraciones(e,1)=e;
                  erroresIteraciones(e,2)=err/app.cantidad_patrones; 
           
            
            disp(err);
                  err=err/app.cantidad_patrones;
                  %graficar el error del entrenamiento 
                  plot(app.UIAxes3,erroresIteraciones(1:e,1),erroresIteraciones(1:e,2),'-r');
           

            pause(0.2);
            e=e+1;
            app.cantidad_CR=app.cantidad_CR+1;
             % Mostramos en la gráfica de simulación los datos de la matriz de simulación
                plot(app.UIAxes2,matrizSimulacion(:,1),matrizSimulacion(:,2),'-b',...
                                           matrizSimulacion(:,1),matrizSimulacion(:,3),'--r');

            end
             disp(app.centro_radial);
             disp(matrizInterpolacion);
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'YD  vs YR')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'ERROR')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.NUMERODECENTROSRADIALESEditFieldLabel = uilabel(app.UIFigure);
            app.NUMERODECENTROSRADIALESEditFieldLabel.Text = {'NUMERO DE CENTROS RADIALES'; ''};
            app.NUMERODECENTROSRADIALESEditField = uieditfield(app.UIFigure, 'numeric');
            app.CargardatosButton = uibutton(app.UIFigure, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosButtonPushed, true);
            app.CargardatosButton.Text = {'Cargar datos'; ''};
            app.ERRORDEAPROXIMACIONEditFieldLabel = uilabel(app.UIFigure);
            app.ERRORDEAPROXIMACIONEditFieldLabel.Text = 'ERROR DE APROXIMACION';
            app.ERRORDEAPROXIMACIONEditField = uieditfield(app.UIFigure, 'numeric');
            app.RutadelarchivoLabel = uilabel(app.UIFigure);
            app.RutadelarchivoLabel.Text = 'Ruta del archivo';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.EntrenarButton = uibutton(app.UIFigure, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.REDNEURONALDEFUNCIONESDEBASERADIALRBFLabel = uilabel(app.UIFigure);
            app.REDNEURONALDEFUNCIONESDEBASERADIALRBFLabel.Text = 'RED NEURONAL DE FUNCIONES DE BASE RADIAL (RBF)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end